classdef transpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRANSPguiUIFigure        matlab.ui.Figure
        ReadTRANSPCDFfileButton  matlab.ui.control.Button
        NUBEAMButton             matlab.ui.control.Button
        NofileopenedLabel        matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        DTab                     matlab.ui.container.Tab
        vars1DListBox            matlab.ui.control.ListBox
        DTab_2                   matlab.ui.container.Tab
        vars2DListBox            matlab.ui.control.ListBox
        timeplot                 matlab.ui.control.UIAxes
        rhoplot                  matlab.ui.control.UIAxes
        NeutralsButton           matlab.ui.control.Button
        TimeLabel                matlab.ui.control.Label
        ExportNUBEAMButton       matlab.ui.control.Button
        TimeEditField_2Label     matlab.ui.control.Label
        TimeEditField            matlab.ui.control.EditField
        ExportrhoplotButton      matlab.ui.control.Button
        ExporttimeplotButton     matlab.ui.control.Button
        EQDSKPanel               matlab.ui.container.Panel
        PrepareEXPEQButton       matlab.ui.control.Button
        runcheaseCheckBox        matlab.ui.control.CheckBox
        TensionEditFieldLabel    matlab.ui.control.Label
        TensionEditField         matlab.ui.control.NumericEditField
        HELPButton               matlab.ui.control.Button
        RangeCheckBox            matlab.ui.control.CheckBox
        CHEASEguiButton          matlab.ui.control.Button
    end


    properties (Access = private)
        time % time
        transpmat % transp matlab structure
        nubeammat
    end
    
    properties (SetObservable)
        fname % name of transp file
        vars_1d % name of 1D vars
        vars_1d_desc % description of 1D vars
        vars_2d % name of 2D vars
        vars_2d_desc % Description of 2D vars 
    end
    
    methods (Access = private)
    
        function splitvars(app, mat_struct)
            app.vars_1d = {};
            app.vars_1d_desc = {};
            app.vars_2d = {};
            app.vars_2d_desc = {};            
            n1d=1;            n2d=1;
            fields=fieldnames(mat_struct.allvars);
            for i=1:length(fields)
                dim=mat_struct.allvars.(fields{i}).Dimensions;
                desc=mat_struct.allvars.(fields{i}).long_name;
                tmp=size(dim);
                if tmp(2) == 1
                    app.vars_1d(n1d) = fields(i);
                    app.vars_1d_desc(n1d) = {[fields{i},': ',desc]};
                    n1d=n1d+1;
                elseif tmp(2) == 2
                    app.vars_2d(n2d) = fields(i);
                    app.vars_2d_desc(n2d) = {[fields{i},': ',desc]};
                    n2d=n2d+1;
                else
                    continue
                end
            end
            app.vars1DListBox.ItemsData = app.vars_1d;
            app.vars1DListBox.Items = app.vars_1d_desc;       
            app.vars2DListBox.ItemsData = app.vars_2d;
            app.vars2DListBox.Items = app.vars_2d_desc;  
        end
        
        
        function store_nubeam(app)
            for i=1:length(app.time)
                app.nubeammat.(sprintf('time%i',i)) = nubeam_get(app.transpmat, app.time(i));
            end
            assignin('base', 'nubeammat', app.nubeammat);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ExportNUBEAMButton
        function ExportNUBEAMButtonPushed(app, event)
            try
                nubeam_write(app.nubeammat.time1)
            catch
                store_nubeam(app)
            end
            for i=1:length(app.time)
                nubeam_write(app.nubeammat.(sprintf('time%i',i)))
            end
        end

        % Button pushed function: ExportrhoplotButton
        function ExportrhoplotNuttonPushed(app, event)
            % Create new figure and new axes
            figure('Name', 'rho plot')
            axNew = axes; 
            % Copy all objects from UIAxes to new axis
            copyobj(app.rhoplot.Children, axNew)
            % Save all parameters of the UIAxes
            uiAxParams = get(app.rhoplot);
            uiAxParamNames = fieldnames(uiAxParams); 
            % Get list of editable params in new axis
            editableParams = fieldnames(set(axNew)); 
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams)); 
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis';'Position';'OuterPosition']; 
            uiAxGoodParams = rmfield(uiAxParams,badFields); 
            % set editable params on new axes
            set(axNew, uiAxGoodParams)
        end

        % Button pushed function: ExporttimeplotButton
        function ExporttimeplotButtonPushed(app, event)
            % Create new figure and new axes
            figure('Name', 'time plot');
            axNew = axes; 
            % Copy all objects from UIAxes to new axis
            copyobj(app.timeplot.Children, axNew)
            % Save all parameters of the UIAxes
            uiAxParams = get(app.timeplot);
            uiAxParamNames = fieldnames(uiAxParams); 
            % Get list of editable params in new axis
            editableParams = fieldnames(set(axNew)); 
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams)); 
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis';'Position';'OuterPosition']; 
            uiAxGoodParams = rmfield(uiAxParams,badFields); 
            % set editable params on new axes
            set(axNew, uiAxGoodParams)
           
        end

        % Button pushed function: PrepareEXPEQButton
        function PrepareEXPEQButtonPushed(app, event)
            [funnetcdf,fname_out, globalsvalues, namelist_struct]=forCHEASE(app.fname,...
                app.transpmat, app.time,app.runcheaseCheckBox.Value, ...
                app.TensionEditField.Value);
            assignin('base', 'funnetcdf', funnetcdf);
            assignin('base', 'fname_out', fname_out);    
            assignin('base', 'globalsvalues', globalsvalues);    
            assignin('base', 'namelist_struct', namelist_struct);            
        end

        % Button pushed function: NUBEAMButton
        function NUBEAMButtonPushed(app, event)
            try
                nubeam_plot(app.nubeammat.time1)
            catch
                store_nubeam(app)
            end
            for i=1:length(app.time)
                nubeam_plot(app.nubeammat.(sprintf('time%i',i)))
            end
        end

        % Button pushed function: NeutralsButton
        function NeutralsButtonPushed(app, event)
            n0_plot(app.transpmat, app.time)
        end

        % Button pushed function: ReadTRANSPCDFfileButton
        function ReadTRANSPCDFfileButtonPushed(app, event)
            [app.fname,pname]=uigetfile('*.cdf','Open NetCDF File');
            app.transpmat = cdf2mat([pname ,app.fname]); 
            app.time = app.transpmat.coords.TIME.data(1);
            app.TimeEditField.Value = num2str(app.time);
            assignin('base', 'fname', app.fname);
            assignin('base', 'transpmat', app.transpmat);
            splitvars(app, app.transpmat)
            app.NofileopenedLabel.Text = ['File opened: ',app.fname];
            
            set(0, 'DefaultLineLineWidth', 2);
            set(0, 'DefaultAxesFontSize',16);
            hold(app.timeplot, 'on')
            plot(app.timeplot, app.transpmat.coords.TIME.data, app.transpmat.allvars.PCUR.data*1e-3, 'k', 'DisplayName', 'Ip (AU)')
            plot(app.timeplot, app.transpmat.coords.TIME.data, app.transpmat.allvars.PINJ.data*1e-3*0.5, 'r', 'DisplayName', 'NBH (AU)')
            hold(app.timeplot, 'off'); legend(app.timeplot, 'show');
            app.TimeLabel.Text = ['[',num2str(min(app.transpmat.coords.TIME.data)),' - ',num2str(max(app.transpmat.coords.TIME.data)),']'];
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            inString = app.TimeEditField.Value;
            strvals = split(inString, ',');
            app.time=zeros(1, length(strvals));
            for i=1:length(strvals)
                app.time(i)=str2double(strvals(i));
            end
            if app.RangeCheckBox.Value
                t = app.transpmat.coords.TIME.data(app.transpmat.coords.TIME.data<max(app.time) & app.transpmat.coords.TIME.data>min(app.time));
                app.time = t;
            end
            %app.time = app.TimeEditField.Value;
            assignin('base', 'time', app.time);
            evalin('base', 'clear nubeammat'); 
            app.nubeammat=struct();
        end

        % Button pushed function: HELPButton
        function helpButtonPushed(app, event)
            tt = ['TRANSP GUI to read a NetCDF file',newline];
            tt = [tt,newline, 'First thing is to read the TRANSP CDF file with the button, which exports also the file to a matlab structure in the workspace',newline];
            tt = [tt, 'You can now select times, writing even an array (comma-separated)',newline];
            tt = [tt, 'NUBEAM button and neutrals button will plot significant NUBEAM and neutrals quantities at the times selected',newline];
            tt = [tt, 'In the EQDSK block, you can set parameters to export an EQDSK (If you do not run CHEASE, only an EXPEQ is produced)',newline];
            tt = [tt, 'You can export NUBEAM as a mat structure',newline];
            tt = [tt, 'You can export the plot you see in the time traces and in the profiles with the relative buttons',newline];
            tt = [tt, 'In the list, you can find 1D and 2D variables in the CDF file',newline];
            tt = [tt, 'Click on one of those to plot them (the 2D quantities will be plot at the times previously selected',newline];
            
            tt = [tt ,newline,newline, 'matteo.vallar@epfl.ch - November 2019 '];
            
            msgbox(tt,'HELP');
        end

        % Value changed function: vars1DListBox
        function plot_1dvar(app, event)
            legend(app.timeplot, 'off');
            value = app.vars1DListBox.Value;
            plot(app.timeplot,app.transpmat.allvars.TIME.data, app.transpmat.allvars.(value).data)
            title(app.timeplot, [app.transpmat.shot, ' ', value]); 
            ylabel(app.timeplot, app.transpmat.allvars.(value).units)
        end

        % Value changed function: vars2DListBox
        function plot_2dvar(app, event)
            value = app.vars2DListBox.Value;
            colors=['k', 'r', 'b', 'm', 'g', 'c', 'y'];
            cla(app.rhoplot)
            for i=1:length(app.time)
                [~,ind] = min(abs(app.transpmat.allvars.TIME.data-app.time(i)));
                hold(app.rhoplot, 'on')
                try
                    plot(app.rhoplot,app.transpmat.allvars.X.data(:,ind), app.transpmat.allvars.(value).data(:,ind), ...,
                        colors(i), 'DisplayName', sprintf('t=%.2f',app.time(i)))
                    title(app.rhoplot, value); legend(app.rhoplot, 'show');
                catch
                    plot(app.rhoplot,app.transpmat.allvars.X.data(:,ind), zeros(1,length(app.transpmat.allvars.X.data(:,ind))), colors(i))
                    title(app.rhoplot, 'NO DATA')
                end
                ylabel(app.rhoplot, app.transpmat.allvars.(value).units)
                hold(app.rhoplot, 'off')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRANSPguiUIFigure = uifigure;
            app.TRANSPguiUIFigure.Name = 'TRANSPgui';
            app.ReadTRANSPCDFfileButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.ReadTRANSPCDFfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadTRANSPCDFfileButtonPushed, true);
            app.ReadTRANSPCDFfileButton.Text = 'Read TRANSP CDF file';
            app.NUBEAMButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.NUBEAMButton.ButtonPushedFcn = createCallbackFcn(app, @NUBEAMButtonPushed, true);
            app.NUBEAMButton.Text = 'NUBEAM';
            app.NofileopenedLabel = uilabel(app.TRANSPguiUIFigure);
            app.NofileopenedLabel.Text = 'No file opened';
            app.TabGroup = uitabgroup(app.TRANSPguiUIFigure);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '1D';
            app.vars1DListBox = uilistbox(app.DTab);
            app.vars1DListBox.Items = {};
            app.vars1DListBox.ValueChangedFcn = createCallbackFcn(app, @plot_1dvar, true);
            app.vars1DListBox.Value = {};
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '2D';
            app.vars2DListBox = uilistbox(app.DTab_2);
            app.vars2DListBox.Items = {};
            app.vars2DListBox.ValueChangedFcn = createCallbackFcn(app, @plot_2dvar, true);
            app.vars2DListBox.Value = {};
            app.timeplot = uiaxes(app.TRANSPguiUIFigure);
            xlabel(app.timeplot, 't (s)')
            app.rhoplot = uiaxes(app.TRANSPguiUIFigure);
            xlabel(app.rhoplot, 'rho_{tor}')
            app.NeutralsButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.NeutralsButton.ButtonPushedFcn = createCallbackFcn(app, @NeutralsButtonPushed, true);
            app.NeutralsButton.Text = 'Neutrals';
            app.TimeLabel = uilabel(app.TRANSPguiUIFigure);
            app.TimeLabel.Text = '';
            app.ExportNUBEAMButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.ExportNUBEAMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNUBEAMButtonPushed, true);
            app.ExportNUBEAMButton.Text = 'Export NUBEAM';
            app.TimeEditField_2Label = uilabel(app.TRANSPguiUIFigure);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField = uieditfield(app.TRANSPguiUIFigure, 'text');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.ExportrhoplotButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.ExportrhoplotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportrhoplotNuttonPushed, true);
            app.ExportrhoplotButton.Text = 'Export rho plot';
            app.ExporttimeplotButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.ExporttimeplotButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttimeplotButtonPushed, true);
            app.ExporttimeplotButton.Text = 'Export time plot';
            app.EQDSKPanel = uipanel(app.TRANSPguiUIFigure);
            app.EQDSKPanel.Title = 'EQDSK';
            app.PrepareEXPEQButton = uibutton(app.EQDSKPanel, 'push');
            app.PrepareEXPEQButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareEXPEQButtonPushed, true);
            app.PrepareEXPEQButton.Text = 'Prepare EXPEQ';
            app.runcheaseCheckBox = uicheckbox(app.EQDSKPanel);
            app.runcheaseCheckBox.Text = 'run chease';
            app.TensionEditFieldLabel = uilabel(app.EQDSKPanel);
            app.TensionEditFieldLabel.Text = 'Tension';
            app.TensionEditField = uieditfield(app.EQDSKPanel, 'numeric');
            app.TensionEditField.Value = 0.0001;
            app.HELPButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.HELPButton.Text = 'HELP';
            app.RangeCheckBox = uicheckbox(app.TRANSPguiUIFigure);
            app.RangeCheckBox.Text = 'Range?';
            app.CHEASEguiButton = uibutton(app.TRANSPguiUIFigure, 'push');
            app.CHEASEguiButton.Text = 'CHEASEgui';
        end
    end

    methods (Access = public)

        % Construct app
        function app = transpGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRANSPguiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRANSPguiUIFigure)
        end
    end
end